classdef DataAnalysisSuite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataAnalysisSuiteUIFigure  matlab.ui.Figure
        OptionsMenu                matlab.ui.container.Menu
        timedimChangeMenu          matlab.ui.container.Menu
        Panel1Plot                 matlab.ui.container.Panel
        UIAxes1                    matlab.ui.control.UIAxes
        Panel2Plot                 matlab.ui.container.Panel
        UIAxes22                   matlab.ui.control.UIAxes
        UIAxes21                   matlab.ui.control.UIAxes
        Panel3Plot                 matlab.ui.container.Panel
        UIAxes31                   matlab.ui.control.UIAxes
        UIAxes32                   matlab.ui.control.UIAxes
        ModeselectionPanel         matlab.ui.container.Panel
        ephysCheckBox              matlab.ui.control.CheckBox
        imagingCheckBox            matlab.ui.control.CheckBox
        runCheckBox                matlab.ui.control.CheckBox
        importPanel                matlab.ui.container.Panel
        ImportRHDButton            matlab.ui.control.Button
        ImportgorobjButton         matlab.ui.control.Button
        ImportruncsvButton         matlab.ui.control.Button
        Dataselection1Panel        matlab.ui.container.Panel
        DatasetListBoxLabel        matlab.ui.control.Label
        DatasetListBox             matlab.ui.control.ListBox
        Dataselection2Panel        matlab.ui.container.Panel
        EphysListBoxLabel          matlab.ui.control.Label
        EphysListBox               matlab.ui.control.ListBox
        ImagingListBoxLabel        matlab.ui.control.Label
        ImagingListBox             matlab.ui.control.ListBox
        runplotPanel               matlab.ui.container.Panel
        UIAxesPos                  matlab.ui.control.UIAxes
        UIAxesVeloc                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timedim = 1;                        % Determines whether the app uses seconds or milliseconds
        datatyp = [0, 0, 0];                % datatypes currently handled (ephys,imaging,running)
        
        xtitle = 'Time [s]';
        
        ephys_data                          % Currently imported electrophysiology data
        ephys_taxis                         % Time axis for electrophysiology data
        ephys_fs                            % Sampling frequency of electrophysiology data
        ephys_ylabel = 'Voltage [\muV]';
        
        imaging_data                        % Currently imported calcium data
        imaging_taxis                       % Time axis for calcium data
        imaging_fs                          % Sampling frequency of calcium data
        imaging_ylabel = '\DeltaF/F';
        
        run_pos                             % Currently imported running data
        run_veloc                           % Running velocity
        run_taxis                           % Time axis for running data
        run_fs                              % Sampling frequency of running data
        run_pos_ylabel = 'Position [%]';
        run_veloc_ylabel = 'Velocity [cm/s]';
    end
    
    methods (Access = private)
        
        function ephysplot(app,ax,index,value)
            plot(ax,app.ephys_taxis,app.ephys_data(index,:))
            axis(ax,'tight')
            title(ax,value,'Interpreter','none')
            xlabel(ax,app.xtitle)
            ylabel(ax,app.ephys_ylabel);
        end
        
        function imagingplot(app,ax,index,value)
            plot(ax,app.imaging_taxis,app.imaging_data(index,:))
            axis(ax,'tight')
            title(ax,value,'Interpreter','none')
            xlabel(ax,app.xtitle)
            ylabel(ax,app.imaging_ylabel);
        end
        
        function runposplot(app,ax)
            plot(ax,app.run_taxis,app.run_pos)
            axis(ax,'tight')
            title(ax,'Position on treadmill','Interpreter','none')
            xlabel(ax,app.xtitle)
            ylabel(ax,app.run_pos_ylabel)
        end
    
        function runvelocplot(app,ax)
            plot(ax,app.run_taxis,app.run_veloc)
            axis(ax,'tight')
            title(ax,'Running velocity','Interpreter','none')
            xlabel(ax,app.xtitle)
            ylabel(ax,app.run_veloc_ylabel)
        end
        
        function plotpanelswitch(app)
            if app.runCheckBox.Value
                switch sum(app.datatyp)
                    case 1
                        app.Panel1Plot.Visible = 'off';
                        app.Panel2Plot.Visible = 'off';
                        app.Panel3Plot.Visible = 'off';
                        app.runplotPanel.Position = [420,270,780,260];
                        app.runplotPanel.Visible = 'on';
                    case 2
                        app.Panel1Plot.Visible = 'off';
                        app.UIAxes22.Visible = 'off';
                        app.Panel2Plot.Visible = 'on';
                        app.Panel3Plot.Visible = 'off';
                        app.runplotPanel.Position = [420,0,780,260];
                        app.runplotPanel.Visible = 'on';                        
                    case 3
                        app.Panel1Plot.Visible = 'off';
                        app.Panel2Plot.Visible = 'off';
                        app.Panel3Plot.Visible = 'on';
                        app.runplotPanel.Position = [420,0,780,260];
                        app.runplotPanel.Visible = 'on';
                end
            elseif ~app.runCheckBox.Value
                switch sum(app.datatyp)
                    case 1
                        app.Panel1Plot.Visible = 'on';
                        app.Panel2Plot.Visible = 'off';
                        app.Panel3Plot.Visible = 'off';
                        app.runplotPanel.Visible = 'off';
                    case 2
                        app.Panel1Plot.Visible = 'off';
                        app.UIAxes22.Visible = 'on';
                        app.Panel2Plot.Visible = 'on';
                        app.Panel3Plot.Visible = 'off';
                        app.runplotPanel.Visible = 'off';                        
                    case 3
                        warndlg('Something isn''t right')
                end
            end
                
        end
    end
 

    methods (Access = private)

        % Button pushed function: ImportRHDButton
        function ImportRHDButtonPushed(app, event)
            [filename,path] = uigetfile('*.rhd');
            drawnow;
            figure(app.DataAnalysisSuiteUIFigure)
            oldpath = cd(path);
            data = read_Intan_RHD2000_file_szb(filename);
            cd(oldpath)
            app.ephys_fs = data.fs;
            t_amp = data.t_amplifier;
            data = data.amplifier_data;
            % Check data dimensions
            if size(data,1) > size(data,2)
                data = data';
            end
            
            % Create and store time axis
            app.ephys_taxis = linspace(t_amp(1),length(data)/app.ephys_fs+t_amp(1),length(data));
                        
            % Store imported data in app
            app.ephys_data = data;
            datanames = cell(1,size(data,1));
            for i = 1:size(data,1)
                datanames{i} = [filename,' channel#',num2str(i)]; 
            end
            
            % Populate selection list with data names
            if app.datatyp(2) == 0
                app.DatasetListBox.Items = datanames;
            elseif app.datatyp(2) == 1
                app.EphysListBox.Items = datanames;
            end
            
%             %%% test
%             app.testdata = 250;
        end

        % Button pushed function: ImportgorobjButton
        function ImportgorobjButtonPushed(app, event)
            % Getting variables from base workspace
            wsvars = evalin('base','whos');
            % Finding gorobj variables
            wsgorinds = strcmp({wsvars.class},'gorobj');
            wsgors = wsvars(wsgorinds);
            % Checking how many gorobjs were detected & if its more than 1, user has to choose
            if length(wsgors)>1
                ind = listdlg('PromptString','Choose which dataset to load!','ListString',{wsgors.name},'SelectionMode','single');
                % Ensuring focus goes back to GUI
                drawnow;
                figure(app.DataAnalysisSuiteUIFigure)
                wsgors = wsgors(ind);
            elseif isempty(wsgors)
                errordlg('There are no gorobj variables in the base workspace (MES Curveanalysis: export -> curves to variable)! Please add them or import data using the other methods!')
                return
            end
            datanames = cell(1,length(wsgors));
            dtyp = zeros(1,length(wsgors));
            % Extracting variable from struct
            wsgors = evalin('base',wsgors.name);
            ie = 1;
            ica = 1;
            for i = 1:length(wsgors)
                if strcmp(get(wsgors(i),'yunit'),'scalar')
                    app.imaging_data(ica,:) = get(wsgors(i),'extracty');
                    dtyp(i) = 2;
                    ica = ica+1;
                else
                    app.ephys_data(ie,:) = get(wsgors(i),'extracty');
                    dtyp(i) = 1;
                    ie = ie+1;
                end
                datanames{i} = get(wsgors(i),'name');
            end

            % Insert data names into selection list and extract time axis, while differentiating between ephys and imaging
            if sum(app.datatyp) == 1 || (sum(app.datatyp)==2 && app.datatyp(3)==1)
                if app.datatyp(1)
                    app.DatasetListBox.Items = datanames(dtyp==1);
                    taxis = get(wsgors(find(dtyp==1,1)),'x')*(10^-3/app.timedim);
                    app.ephys_fs = 1/taxis(2);
                    app.ephys_taxis = linspace(taxis(1),length(app.ephys_data)/app.ephys_fs+taxis(1),length(app.ephys_data));
                elseif app.datatyp(2)
                    app.DatasetListBox.Items = datanames(dtyp==2);
                    taxis = get(wsgors(find(dtyp==2,1)),'x')*(10^-3/app.timedim);
                    app.imaging_fs = 1/taxis(2);
                    app.imaging_taxis = linspace(taxis(1),length(app.imaging_data)/app.imaging_fs+taxis(1),length(app.imaging_data));
                end
            elseif app.datatyp(1) && app.datatyp(2)
                app.EphysListBox.Items = datanames(dtyp==1);
                app.ImagingListBox.Items = datanames(dtyp==2);
                
                taxis = get(wsgors(find(dtyp==1,1)),'x')*(10^-3/app.timedim);
                app.ephys_fs = 1/taxis(2);
                app.ephys_taxis = linspace(taxis(1),length(app.ephys_data)/app.ephys_fs+taxis(1),length(app.ephys_data));
                    
                taxis = get(wsgors(find(dtyp==2,1)),'x')*(10^-3/app.timedim);
                app.imaging_fs = 1/taxis(2);
                app.imaging_taxis = linspace(taxis(1),length(app.imaging_data)/app.imaging_fs+taxis(1),length(app.imaging_data));
            end
            
%             %%% test
%             uzi = sprintf('This is the number: %d',app.testdata);
%             warndlg(uzi)
        end

        % Value changed function: DatasetListBox
        function DatasetListBoxValueChanged(app, event)
            value = app.DatasetListBox.Value;
            [~, index] = ismember(value, app.DatasetListBox.Items);
            % Check which data mode we are in and plot accordingly
            if app.datatyp(3)
                if app.datatyp(1)
                    ephysplot(app,app.UIAxes21,index,value)
                elseif app.datatyp(2)
                    imagingplot(app,app.UIAxes21,index,value)
                end
            elseif ~app.datatyp(3)
                if app.datatyp(1)
                    ephysplot(app,app.UIAxes1,index,value)
                elseif app.datatyp(2)
                    imagingplot(app,app.UIAxes1,index,value)
                end
            end
        end

        % Menu selected function: timedimChangeMenu
        function timedimChangeMenuSelected(app, event)
            if app.timedim == 1
                app.xtitle = 'Time [ms]';
                
                app.ephys_taxis = app.ephys_taxis*10^3;
                
                app.imaging_taxis = app.imaging_taxis*10^3;
                
                app.run_taxis = app.run_taxis*10^3;
                
                app.timedim = 10^-3;
            elseif app.timedim == 10^-3
                app.xtitle = 'Time [s]';
                
                app.ephys_taxis = app.ephys_taxis/10^3;
                
                app.imaging_taxis = app.imaging_taxis/10^3;

                app.run_taxis = app.run_taxis/10^3;
                
                app.timedim = 1;
            end
            
            if app.datatyp(3)
                runposplot(app,app.UIAxesPos)
                runvelocplot(app,app.UIAxesVeloc)
            end
            if strcmp(app.Dataselection1Panel.Visible,'on')
                DatasetListBoxValueChanged(app,event);
            elseif strcmp(app.Dataselection2Panel.Visible,'on')           
                EphysListBoxValueChanged(app,event);
                ImagingListBoxValueChanged(app,event);
            end
        end

        % Button pushed function: ImportruncsvButton
        function ImportruncsvButtonPushed(app, event)
            % Reading the running data from the csv
            [filename,path] = uigetfile('*.csv');
%             drawnow;
            figure(app.DataAnalysisSuiteUIFigure)
            oldpath = cd(path);
            % csvread shouldnt be used starting 2019a !!!
            rundata = csvread(filename,1,0);
            cd(oldpath)
            app.run_pos = rundata(1:end-1,2);
            app.run_veloc = diff(rundata(:,2))./diff(rundata(:,1));
            app.run_taxis = rundata(1:end-1,1)*(10^-3)/app.timedim;
            app.run_fs = mean(gradient(rundata(:,1)));
            
            runposplot(app,app.UIAxesPos)
            runvelocplot(app,app.UIAxesVeloc)
        end

        % Value changed function: ephysCheckBox
        function ephysCheckBoxValueChanged(app, event)
            % Determining whether ephys modules are needed
            value = app.ephysCheckBox.Value;
            % Import buttons
            if value
                app.ImportRHDButton.Enable = 'on';
                app.ImportgorobjButton.Enable = 'on';
            elseif ~value
                app.ImportRHDButton.Enable = 'off';
                if ~app.imagingCheckBox.Value
                    app.ImportgorobjButton.Enable = 'off';                    
                end
            end
            
            % Plot panel switching
            app.datatyp = [app.ephysCheckBox.Value, app.imagingCheckBox.Value, app.runCheckBox.Value];
            plotpanelswitch(app)
            
            % Data set panel swithcing
            if value && app.imagingCheckBox.Value
                app.Dataselection2Panel.Visible = 'on';
                app.Dataselection1Panel.Visible = 'off';
            elseif value && ~app.imagingCheckBox.Value
                app.Dataselection1Panel.Visible = 'on';
                app.Dataselection2Panel.Visible = 'off';
            elseif ~value && app.imagingCheckBox.Value
                app.Dataselection1Panel.Visible = 'on';
                app.Dataselection2Panel.Visible = 'off';
            elseif ~value && ~app.imagingCheckBox.Value
                app.Dataselection1Panel.Visible = 'on';
                app.Dataselection2Panel.Visible = 'off';
            end
        end

        % Value changed function: imagingCheckBox
        function imagingCheckBoxValueChanged(app, event)
            % Determining whether imaging data modules are needed
            value = app.imagingCheckBox.Value;
            
            % Import buttons
            if value 
                app.ImportgorobjButton.Enable = 'on';
            elseif ~value && ~app.ephysCheckBox.Value
                app.ImportgorobjButton.Enable = 'off';
            end
            
            % Plot panel switching
            app.datatyp = [app.ephysCheckBox.Value, app.imagingCheckBox.Value, app.runCheckBox.Value];
            plotpanelswitch(app)
            
            % Data set panel swithcing
            if value && app.ephysCheckBox.Value
                app.Dataselection2Panel.Visible = 'on';
                app.Dataselection1Panel.Visible = 'off';
            elseif value && ~app.ephysCheckBox.Value
                app.Dataselection1Panel.Visible = 'on';
                app.Dataselection2Panel.Visible = 'off';
            elseif ~value && app.ephysCheckBox.Value
                app.Dataselection1Panel.Visible = 'on';
                app.Dataselection2Panel.Visible = 'off';
            elseif ~value && ~app.ephysCheckBox.Value
                app.Dataselection1Panel.Visible = 'on';
                app.Dataselection2Panel.Visible = 'off';
            end
        end

        % Value changed function: runCheckBox
        function runCheckBoxValueChanged(app, event)
            % Determining whether running data modules are needed
            value = app.runCheckBox.Value;
            
            % Import buttons
            if value 
                app.ImportruncsvButton.Enable = 'on';
            elseif ~value
                app.ImportruncsvButton.Enable = 'off';
            end
            
            % Plot panel switching
            app.datatyp = [app.ephysCheckBox.Value, app.imagingCheckBox.Value, app.runCheckBox.Value];
            plotpanelswitch(app)
        end

        % Value changed function: EphysListBox
        function EphysListBoxValueChanged(app, event)
            value = app.EphysListBox.Value;
            [~, index] = ismember(value, app.EphysListBox.Items);
            if sum(app.datatyp) == 2
                ephysplot(app,app.UIAxes21,index,value)
            elseif sum(app.datatyp) == 3
                ephysplot(app,app.UIAxes31,index,value)
            end
        end

        % Value changed function: ImagingListBox
        function ImagingListBoxValueChanged(app, event)
            value = app.ImagingListBox.Value;
            [~, index] = ismember(value, app.ImagingListBox.Items);
            if sum(app.datatyp) == 2
                imagingplot(app,app.UIAxes22,index,value)
            elseif sum(app.datatyp) == 3
                imagingplot(app,app.UIAxes32,index,value)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataAnalysisSuiteUIFigure = uifigure;
            app.DataAnalysisSuiteUIFigure.Name = 'Data Analysis Suite';
            app.OptionsMenu = uimenu(app.DataAnalysisSuiteUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.timedimChangeMenu = uimenu(app.OptionsMenu);
            app.timedimChangeMenu.MenuSelectedFcn = createCallbackFcn(app, @timedimChangeMenuSelected, true);
            app.timedimChangeMenu.Text = 'Change between [s]/[ms]';
            app.Panel1Plot = uipanel(app.DataAnalysisSuiteUIFigure);
            app.UIAxes1 = uiaxes(app.Panel1Plot);
            app.Panel2Plot = uipanel(app.DataAnalysisSuiteUIFigure);
            app.Panel2Plot.Visible = 'off';
            app.UIAxes22 = uiaxes(app.Panel2Plot);
            app.UIAxes21 = uiaxes(app.Panel2Plot);
            app.Panel3Plot = uipanel(app.DataAnalysisSuiteUIFigure);
            app.Panel3Plot.Visible = 'off';
            app.UIAxes31 = uiaxes(app.Panel3Plot);
            app.UIAxes32 = uiaxes(app.Panel3Plot);
            app.ModeselectionPanel = uipanel(app.DataAnalysisSuiteUIFigure);
            app.ModeselectionPanel.Title = 'Mode selection';
            app.ephysCheckBox = uicheckbox(app.ModeselectionPanel);
            app.ephysCheckBox.ValueChangedFcn = createCallbackFcn(app, @ephysCheckBoxValueChanged, true);
            app.ephysCheckBox.Text = 'Electrophysiology';
            app.imagingCheckBox = uicheckbox(app.ModeselectionPanel);
            app.imagingCheckBox.ValueChangedFcn = createCallbackFcn(app, @imagingCheckBoxValueChanged, true);
            app.imagingCheckBox.Text = 'Imaging data (Ca2+)';
            app.runCheckBox = uicheckbox(app.ModeselectionPanel);
            app.runCheckBox.ValueChangedFcn = createCallbackFcn(app, @runCheckBoxValueChanged, true);
            app.runCheckBox.Text = 'Running data';
            app.importPanel = uipanel(app.DataAnalysisSuiteUIFigure);
            app.importPanel.Title = 'Import data';
            app.ImportRHDButton = uibutton(app.importPanel, 'push');
            app.ImportRHDButton.ButtonPushedFcn = createCallbackFcn(app, @ImportRHDButtonPushed, true);
            app.ImportRHDButton.Enable = 'off';
            app.ImportRHDButton.Text = 'Import RHD';
            app.ImportgorobjButton = uibutton(app.importPanel, 'push');
            app.ImportgorobjButton.ButtonPushedFcn = createCallbackFcn(app, @ImportgorobjButtonPushed, true);
            app.ImportgorobjButton.Enable = 'off';
            app.ImportgorobjButton.Text = 'Import gorobj';
            app.ImportruncsvButton = uibutton(app.importPanel, 'push');
            app.ImportruncsvButton.ButtonPushedFcn = createCallbackFcn(app, @ImportruncsvButtonPushed, true);
            app.ImportruncsvButton.Enable = 'off';
            app.ImportruncsvButton.Text = 'Import run csv';
            app.Dataselection1Panel = uipanel(app.DataAnalysisSuiteUIFigure);
            app.Dataselection1Panel.Title = 'Data selection';
            app.DatasetListBoxLabel = uilabel(app.Dataselection1Panel);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.Dataselection1Panel);
            app.DatasetListBox.Items = {};
            app.DatasetListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetListBoxValueChanged, true);
            app.DatasetListBox.Value = {};
            app.Dataselection2Panel = uipanel(app.DataAnalysisSuiteUIFigure);
            app.Dataselection2Panel.Title = 'Data selection';
            app.Dataselection2Panel.Visible = 'off';
            app.EphysListBoxLabel = uilabel(app.Dataselection2Panel);
            app.EphysListBoxLabel.Text = 'Ephys';
            app.EphysListBox = uilistbox(app.Dataselection2Panel);
            app.EphysListBox.Items = {};
            app.EphysListBox.ValueChangedFcn = createCallbackFcn(app, @EphysListBoxValueChanged, true);
            app.EphysListBox.Value = {};
            app.ImagingListBoxLabel = uilabel(app.Dataselection2Panel);
            app.ImagingListBoxLabel.Text = 'Imaging';
            app.ImagingListBox = uilistbox(app.Dataselection2Panel);
            app.ImagingListBox.Items = {};
            app.ImagingListBox.ValueChangedFcn = createCallbackFcn(app, @ImagingListBoxValueChanged, true);
            app.ImagingListBox.Value = {};
            app.runplotPanel = uipanel(app.DataAnalysisSuiteUIFigure);
            app.runplotPanel.Visible = 'off';
            app.UIAxesPos = uiaxes(app.runplotPanel);
            app.UIAxesVeloc = uiaxes(app.runplotPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = DataAnalysisSuite

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataAnalysisSuiteUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataAnalysisSuiteUIFigure)
        end
    end
end